classdef TicTacToe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image3                     matlab.ui.control.Image
        Image2_2                   matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        Label_5                    matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        CatImage                   matlab.ui.control.Image
        RestartGameButton          matlab.ui.control.Button
        EndGameButton              matlab.ui.control.Button
        ZachSparks2147868BereketMego2160314YusufKhan2165474Label  matlab.ui.control.Label
        CommentsLabel              matlab.ui.control.Label
        TurnTrackerEditField       matlab.ui.control.EditField
        TurnTrackerEditFieldLabel  matlab.ui.control.Label
        PlaceButton                matlab.ui.control.Button
        TopRight                   matlab.ui.control.EditField
        TopMiddle                  matlab.ui.control.EditField
        TopLeft                    matlab.ui.control.EditField
        MiddleLeft                 matlab.ui.control.EditField
        MiddleMiddle               matlab.ui.control.EditField
        MiddleRight                matlab.ui.control.EditField
        BottomLeft                 matlab.ui.control.EditField
        BottomMiddle               matlab.ui.control.EditField
        BottomRight                matlab.ui.control.EditField
        StartButton                matlab.ui.control.Button
        Down                       matlab.ui.control.Button
        Right                      matlab.ui.control.Button
        Up                         matlab.ui.control.Button
        Left                       matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
    end


    % Variables needed in the app

    properties (Access = private)
        UserBoard = zeros(3,3);
        CompBoard = zeros(3,3);
        GameBoard = zeros(3,3);
        GameCondition = 0;
        BoardSquares = ["app.TopLeft" "app.TopMiddle" "app.TopRight"; "app.MiddleLeft" "app.MiddleMiddle" "app.MiddleRight";"app.BottomLeft" "app.BottomMiddle" "app.BottomRight"];
        Pos = zeros(3,3);
        OpenSpots
        Turn

        
        % arduino stuff        
        Arduino = arduino('/dev/cu.usbmodem146101','Uno' )      
        PinLabels = ["D13" "D12" "D11"; "D10" "D9" "D8"; "D7" "D6" "D5"];       
        Off = zeros(3,3);
        On = ones(3,3)
        

    end

    methods (Access = private)
        %% Function to update the cursor position
        function  UpdateCursor(app,BoardSquares,Pos,GameBoard)
            for Row = 1:3
                for Col = 1:3
                    if Pos(Row,Col) == 1                   
                        set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor','g');                       
                    else
                        set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor','white');
                    end
                end
            end
        end

%% function to update the ardunio pins w/gameboard values                  
function  UpdatePins(app,Arduino,PinLabels,Board)               
            [R,C] = size(PinLabels);     
            for Rows = 1:R
                for Cols = 1:C
                    writeDigitalPin(Arduino, PinLabels(Rows,Cols), Board(Rows,Cols)); 
                end 
            end 
end 

%% to make the pin where the cursor is blink
 function  CursorBlink(app,Arduino,PinLabels,Pos,GameBoard)
       
             %finds all the places the cursorboard should be on
             [Row,Col] = find(Pos == 1);      
             writeDigitalPin(Arduino, PinLabels(Row,Col), 1);
             pause(0.2)
             writeDigitalPin(Arduino, PinLabels(Row,Col), 0);
             if app.GameBoard(Row,Col) == 1
                 writeDigitalPin(Arduino, PinLabels(Row,Col), 1);
             else
             end 

 end  

%% Function for Computer move
        function[CompBoard,GameBoard] = ComputerMove(app,UserBoard,CompBoard,GameBoard,OpenSpots,BoardSquares)

            app.OpenSpots = find(app.GameBoard == 0);
            % making computer pick a random place from the open spot
            if length(app.OpenSpots) ~= 0
            RandIndex = randi([1,length(app.OpenSpots)],1);
            RandChoice = app.OpenSpots(RandIndex);
        
            % updating the computers board and the game board with its choice
            app.CompBoard(RandChoice) = 1;
            set(eval(app.BoardSquares(RandChoice)), 'Value','O');
            app.GameBoard = app.UserBoard + app.CompBoard;
            app.TurnTrackerEditField.Value = "User";
            end
        end

%% Function to check for a win
function WinChecker(app,UserBoard,CompBoard,GameCondition,Turn)
                for ROW = 1:3
                    for COLUMN = 1:3 % checking for possible wins
                        if sum(sum(app.UserBoard(ROW,:),2)) == 3 || sum(sum(app.UserBoard(:,COLUMN),1)) == 3 || trace(app.UserBoard) == 3 || trace(fliplr(app.UserBoard)) == 3
                            app.TurnTrackerEditField.Value = " " ;


            %Start screen animation code
            message = 'The Player Wins!';
            runningmessage = '';
            for i = 1:length(message)
                runningmessage =  append(runningmessage,message(i));% Display the current character
                app.CommentsLabel.Text = runningmessage;
                pause(0.05); % Pause for a short time to create the effect of typing
            end
            %Screen Animation code end 


                            app.Image.ImageSource = imread('computercatloses.jpg');
                            app.GameCondition = 1;
                            writeDigitalPin(app.Arduino,"D4",1)
                            pause(0.2)
                            writeDigitalPin(app.Arduino,"D4",0)
                            UpdatePins(app,app.Arduino,app.PinLabels,app.Off)
                            for n = 1:3
                                UpdatePins(app,app.Arduino,app.PinLabels,app.On)
                                pause(0.3)
                                UpdatePins(app,app.Arduino,app.PinLabels,app.Off)
                            end
                            break 

                            %checking to see if computer won
                        elseif sum(sum(app.CompBoard(ROW,:),2)) == 3 || sum(sum(app.CompBoard(:,COLUMN),1)) == 3 || trace(app.CompBoard) == 3 || trace(fliplr(app.CompBoard)) == 3
                            app.TurnTrackerEditField.Value = " " ;


            %Start screen animation code
            message = 'Computer Cat Wins';
            runningmessage = '';
            for i = 1:length(message)
                runningmessage =  append(runningmessage,message(i));% Display the current character
                app.CommentsLabel.Text = runningmessage;
                pause(0.05); % Pause for a short time to create the effect of typing
            end
            %Screen Animation code end 


                            app.Image.ImageSource = imread('computercatwins.jpg');
                            app.GameCondition = 1;
                            UpdatePins(app,app.Arduino,app.PinLabels,app.Off)
                            writeDigitalPin(app.Arduino,"D4",1)
                            pause(0.3)
                            writeDigitalPin(app.Arduino,"D4",0)
                            break 
                        elseif app.GameCondition == 0 && app.Turn == 9


                            %Start screen animation code
            message = 'It is a draw...';
            runningmessage = '';
            for i = 1:length(message)
                runningmessage =  append(runningmessage,message(i));% Display the current character
                app.CommentsLabel.Text = runningmessage;
                pause(0.05); % Pause for a short time to create the effect of typing
            end
            %Screen Animation code end 


                         app.Image.ImageSource = "BlankPng.png";
                        end
                    end
                end
end
            end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%% Start
            % giving the center square a value of one
            app.GameCondition = 0;
            

            for i = 1:3
                for j = 1:3
                    app.Pos(i,j) = 0;
                end
            end

            for Row = 1:3
                for Col = 1:3
                    set(eval(app.BoardSquares(Row,Col)), 'Value',' ');
                    set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor','white');
                end
            end

            writeDigitalPin(app.Arduino,"D4",0)
            app.UserBoard(:,:) = 0;
            app.CompBoard(:,:) = 0;
            app.GameBoard = app.UserBoard + app.CompBoard;
            app.TurnTrackerEditField.Value = " ";
            app.CommentsLabel.Text = " ";
            app.Image.ImageSource = "BlankPng.png";
            UpdatePins(app,app.Arduino,app.PinLabels,app.Off)
            pause(0.5)


            app.Pos(2,2) = 1;


            %calling our cursor function to turn the center square green
            UpdateCursor(app,app.BoardSquares,app.Pos,app.GameBoard)
            app.TurnTrackerEditField.Value = "User";  

            % using a while loop to constantly update new pins
            while app.GameCondition == 0
                    %UpdatePins(app,app.Arduino,app.PinLabels,app.GameBoard)
                    pause(0.2)
                    CursorBlink(app,app.Arduino,app.PinLabels,app.Pos)
%                     if sum(app.GameBoard,"all") == 9 
%                         app.CommentsLabel.Text = "It is a Draw";
%                         break 
%                     end 
            end

        end

        % Button pushed function: Up
        function UpPushed(app, event)
%% Up arrow
            % updating cursor position
            [NewRow, NewCol] = find(app.Pos == 1);

            % resetting old square
            app.Pos(NewRow,NewCol) = 0;

            % setting new cursor position
            if NewRow == 1
                NewRow = 4;
            end
            app.Pos(NewRow-1,NewCol) = 1;
            UpdateCursor(app,app.BoardSquares,app.Pos,app.GameBoard)

        end

        % Button pushed function: Down
        function DownPushed(app, event)
%% Down Arrow
            % updating cursor position
            [NewRow, NewCol] = find(app.Pos == 1);

            % resetting old square
            app.Pos(NewRow,NewCol) = 0;

            % setting new cursor position
            if NewRow == 3
                NewRow = 0;
            end
            app.Pos(NewRow+1,NewCol) = 1;
            UpdateCursor(app,app.BoardSquares,app.Pos,app.GameBoard)

        end

        % Button pushed function: Left
        function LeftPushed(app, event)
%% Left arrow
            % updating cursor position
            [NewRow, NewCol] = find(app.Pos == 1);

            % resetting old square
            app.Pos(NewRow,NewCol) = 0;

            % setting new cursor position
            if NewCol == 1
                NewCol = 4;
            end
            app.Pos(NewRow,NewCol-1) = 1;
            UpdateCursor(app,app.BoardSquares,app.Pos,app.GameBoard)

        end

        % Button pushed function: Right
        function RightPushed(app, event)
%% Right arrow
            % updating cursor position
            [NewRow, NewCol] = find(app.Pos == 1);

            % resetting old square
            app.Pos(NewRow,NewCol) = 0;
            
            % setting new cursor position
            if NewCol == 3
                NewCol = 0;
            end
            app.Pos(NewRow,NewCol+1) = 1;
            UpdateCursor(app,app.BoardSquares,app.Pos,app.GameBoard)

        end

        % Button pushed function: PlaceButton
        function PlaceButtonPushed(app, event)
%% if user presses the Place button
            [Row,Col] = find(app.Pos == 1);
            app.GameBoard = app.UserBoard + app.CompBoard;
            
            % if a winner hasnt been found 
            if app.GameBoard(Row,Col) == 0
            
                % User move if its the User's turn
                if strcmp(app.TurnTrackerEditField.Value,"User") == 1 
                    app.UserBoard(Row,Col) = 1;
                    set(eval(app.BoardSquares(Row,Col)), 'Value','X')        
            
                    % updating gameboard and checking if there is a win on the board
                    app.GameBoard = app.UserBoard + app.CompBoard;
                    UpdatePins(app,app.Arduino,app.PinLabels,app.GameBoard)
                    app.Turn = sum(app.GameBoard,"all"); 
                    WinChecker(app,app.UserBoard,app.CompBoard,app.GameCondition,app.Turn);
                    

 %% computer Move                    
                    % if a winner hasnt been found  after the user's turn
                    if app.GameCondition == 0 && app.Turn <=9
                        app.TurnTrackerEditField.Value = "Computer"; % cosmetic stuff for display
                        app.CatImage.ImageSource = "CatMouse.gif";
                        pause(.8)

                        % Call the computer to make a move
                        ComputerMove(app,app.UserBoard,app.CompBoard,app.GameBoard,app.OpenSpots,app.BoardSquares)
                        app.GameBoard = app.UserBoard + app.CompBoard;
                        UpdatePins(app,app.Arduino,app.PinLabels,app.GameBoard)

                        % Check for a win
                        app.Turn = sum(app.GameBoard,"all"); 
                        WinChecker(app,app.UserBoard,app.CompBoard,app.GameCondition,app.Turn);
                        app.CatImage.ImageSource = "BlankPng.png";
                        
                    end 
                    
                end

                % Checking to see if all the squares are filled and a win
                % hasn't been found yet 
          
            
                % gives the user an error message and visual feedback if
                % they try to play in a filled square
            else
                app.CommentsLabel.Text = "Cannot play there";
                set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor','red');
                pause(0.1)
                set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor','white');
                pause(0.1)
                set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor','red');
                pause(0.1)
                set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor','white');
                pause(0.1)
                set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor','g');
                pause(0.2)
                app.CommentsLabel.Text = " ";
            end

        end

        % Button pushed function: EndGameButton
        function EndGameButtonPushed(app, event)

            % resetting all the colors and boards 


    for Row = 1:3
                for Col = 1:3
                    set(eval(app.BoardSquares(Row,Col)), 'Value',' ');
                    set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor',[.9411, .06666, .06666]);
                        pause(.08);
                end
    end


            for Row = 1:3
                for Col = 1:3
                    set(eval(app.BoardSquares(Row,Col)), 'Value',' ');
                    set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor','white');
                    pause(.08);
                end
            end


            writeDigitalPin(app.Arduino,"D4",0)
            app.UserBoard(:,:) = 0;
            app.CompBoard(:,:) = 0;
            app.GameBoard = app.UserBoard + app.CompBoard;
            app.TurnTrackerEditField.Value = " ";
            app.CommentsLabel.Text = " ";
            app.Image.ImageSource = "BlankPng.png";
            UpdatePins(app,app.Arduino,app.PinLabels,app.Off)
            pause(0.5)

            
            for Row = 1:3
                for Col = 1:3
                    set(eval(app.BoardSquares(Row,Col)), 'Value',' ');
                    set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor','white');
                end
            end

            writeDigitalPin(app.Arduino,"D4",0)      
            app.UserBoard(:,:) = 0;
            app.CompBoard(:,:) = 0;
            app.GameBoard = app.UserBoard + app.CompBoard;
            app.Pos(:,:) = 0;
            app.TurnTrackerEditField.Value = " ";
            app.CommentsLabel.Text = " ";
            UpdatePins(app,app.Arduino,app.PinLabels,app.Off)
            app.GameCondition = 0;
            app.Image.ImageSource = "BlankPng.png";

        end

        % Button pushed function: RestartGameButton
        function RestartGameButtonPushed(app, event)
%% clearing everything
            

for index = 1:3

    for Row = 1:3
                for Col = 1:3
                    set(eval(app.BoardSquares(Row,Col)), 'Value',' ');
                    set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor',[.1725, .9607, .93333]);
                end
    end

    pause(.1);

            for Row = 1:3
                for Col = 1:3
                    set(eval(app.BoardSquares(Row,Col)), 'Value',' ');
                    set(eval(app.BoardSquares(Row,Col)), 'BackgroundColor','white');
                end
            end

            pause(.1);
end
            writeDigitalPin(app.Arduino,"D4",0)
            app.UserBoard(:,:) = 0;
            app.CompBoard(:,:) = 0;
            app.GameBoard = app.UserBoard + app.CompBoard;
            app.TurnTrackerEditField.Value = " ";
            app.CommentsLabel.Text = " ";
            app.Image.ImageSource = "BlankPng.png";
            UpdatePins(app,app.Arduino,app.PinLabels,app.Off)
            pause(0.5)

%% Starting game code
            
            app.GameCondition = 0;
            app.Pos(:,:) = 0;
            app.Pos(2,2) = 1;

            %calling our cursor function to turn the center square green
            UpdateCursor(app,app.BoardSquares,app.Pos,app.GameBoard)
            app.TurnTrackerEditField.Value = "User";  

            % using a while loop to constantly update new pins
            while app.GameCondition == 0
                    %UpdatePins(app,app.Arduino,app.PinLabels,app.GameBoard)
                    pause(0.2)
                    CursorBlink(app,app.Arduino,app.PinLabels,app.Pos)
                    if sum(app.GameBoard,"all") == 9 
                        app.CommentsLabel.Text = "It is a Draw";
                        break 
                    end 
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Left = uibutton(app.UIFigure, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftPushed, true);
            app.Left.Icon = fullfile(pathToMLAPP, 'pngwing.com.png');
            app.Left.Text = '';
            app.Up = uibutton(app.UIFigure, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpPushed, true);
            app.Up.Icon = fullfile(pathToMLAPP, 'UpArrow.png');
            app.Up.Text = '';
            app.Right = uibutton(app.UIFigure, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightPushed, true);
            app.Right.Icon = fullfile(pathToMLAPP, 'RightArrow.png');
            app.Right.Text = '';
            app.Down = uibutton(app.UIFigure, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownPushed, true);
            app.Down.Icon = fullfile(pathToMLAPP, 'DownArrow.png');
            app.Down.Text = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = fullfile(pathToMLAPP, 'Start.png');
            app.StartButton.Text = 'Start';
            app.BottomRight = uieditfield(app.UIFigure, 'text');
            app.BottomRight.Editable = 'off';
            app.BottomMiddle = uieditfield(app.UIFigure, 'text');
            app.BottomMiddle.Editable = 'off';
            app.BottomLeft = uieditfield(app.UIFigure, 'text');
            app.BottomLeft.Editable = 'off';
            app.MiddleRight = uieditfield(app.UIFigure, 'text');
            app.MiddleRight.Editable = 'off';
            app.MiddleMiddle = uieditfield(app.UIFigure, 'text');
            app.MiddleMiddle.Editable = 'off';
            app.MiddleLeft = uieditfield(app.UIFigure, 'text');
            app.MiddleLeft.Editable = 'off';
            app.TopLeft = uieditfield(app.UIFigure, 'text');
            app.TopLeft.Editable = 'off';
            app.TopMiddle = uieditfield(app.UIFigure, 'text');
            app.TopMiddle.Editable = 'off';
            app.TopRight = uieditfield(app.UIFigure, 'text');
            app.TopRight.Editable = 'off';
            app.PlaceButton = uibutton(app.UIFigure, 'push');
            app.PlaceButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceButtonPushed, true);
            app.PlaceButton.Text = 'Place';
            app.TurnTrackerEditFieldLabel = uilabel(app.UIFigure);
            app.TurnTrackerEditFieldLabel.Text = 'Turn Tracker';
            app.TurnTrackerEditField = uieditfield(app.UIFigure, 'text');
            app.CommentsLabel = uilabel(app.UIFigure);
            app.CommentsLabel.Text = '';
            app.ZachSparks2147868BereketMego2160314YusufKhan2165474Label = uilabel(app.UIFigure);
            app.ZachSparks2147868BereketMego2160314YusufKhan2165474Label.Text = {'Zach Sparks 2147868 Bereket Mego 2160314    '; 'Yusuf Khan 2165474 Professor Landon'};
            app.EndGameButton = uibutton(app.UIFigure, 'push');
            app.EndGameButton.ButtonPushedFcn = createCallbackFcn(app, @EndGameButtonPushed, true);
            app.EndGameButton.Icon = fullfile(pathToMLAPP, 'ProhibitionSign.png');
            app.EndGameButton.Text = 'End Game';
            app.RestartGameButton = uibutton(app.UIFigure, 'push');
            app.RestartGameButton.ButtonPushedFcn = createCallbackFcn(app, @RestartGameButtonPushed, true);
            app.RestartGameButton.Icon = fullfile(pathToMLAPP, 'Restart.png');
            app.RestartGameButton.Text = 'Restart Game';
            app.CatImage = uiimage(app.UIFigure);
            app.CatImage.ImageSource = fullfile(pathToMLAPP, 'BlankPng.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BlankPng.png');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'PngDeco.png');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'PngDecoInverted.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-04-23 at 1.51.33 AM.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TicTacToe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end